classdef IAD_CAPTURE_SOLVE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SampleNameEditFieldLabel        matlab.ui.control.Label
        SampleNameEditField             matlab.ui.control.EditField
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        IntegrationTimesEditField       matlab.ui.control.EditField
        ScanstoAverageEditFieldLabel    matlab.ui.control.Label
        ScanstoAverageEditField         matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.EditField
        BeamWidthmmEditFieldLabel       matlab.ui.control.Label
        BeamWidthmmEditField            matlab.ui.control.EditField
        SampleThicknessmmEditFieldLabel  matlab.ui.control.Label
        SampleThicknessmmEditField      matlab.ui.control.EditField
        MaterialRefractiveIndexEditFieldLabel  matlab.ui.control.Label
        MaterialRefractiveIndexEditField  matlab.ui.control.EditField
        WavelengthRangenmEditFieldLabel  matlab.ui.control.Label
        WavelengthRangenmEditField      matlab.ui.control.EditField
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.EditField
        CaptureButton                   matlab.ui.control.Button
        SolveIADButton                  matlab.ui.control.Button
        MeasurementDropDownLabel        matlab.ui.control.Label
        MeasurementDropDown             matlab.ui.control.DropDown
        ConnectedLamp                   matlab.ui.control.Lamp
        PreviewSwitchLabel              matlab.ui.control.Label
        PreviewSwitch                   matlab.ui.control.Switch
        LoadButton                      matlab.ui.control.Button
        NumberofPhotonsEditFieldLabel   matlab.ui.control.Label
        NumberofPhotonsEditField        matlab.ui.control.EditField
        IADInputLabel                   matlab.ui.control.Label
        SpectralAcquisitionLabel        matlab.ui.control.Label
        StatusTextAreaLabel             matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        GetMRMTandcreateIADinputButton  matlab.ui.control.Button
        AnisotropyfactorgEditFieldLabel  matlab.ui.control.Label
        AnisotropyfactorgEditField      matlab.ui.control.EditField
        SpecificWavelengthEditFieldLabel  matlab.ui.control.Label
        SpecificWavelengthEditField     matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        AbsorptionatSpecifiedWavelengthLabel  matlab.ui.control.Label
        AbsorptionatSpecifiedWavelengthEditField  matlab.ui.control.NumericEditField
        ScatteringatSpecifiedWavelengthEditFieldLabel  matlab.ui.control.Label
        ScatteringatSpecifiedWavelengthEditField  matlab.ui.control.NumericEditField
        MaximumIntensityEditFieldLabel  matlab.ui.control.Label
        MaximumIntensityEditField       matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        spectrometerObj % The spectrometer object
        previewTimer    % Timer for updating the preview
        spectrometerIndex  % Description
        channelIndex
        integrationTime % Description
        wavelengths % Description
        intensities % Description
        InputFilePath % Description
        MR % Description
        MT % Description
        Wavelengths % Description
        SavePNGAnswer % Description
        RememberSavePNGAnswer % Description
    end
    
    methods (Access = private)
        
        
        function connectToSpectrometer(app)
            % Create an OmniDriver object
            app.spectrometerObj = icdevice('OceanOptics_OmniDriver.mdd');
            pause(3)
            try
                % Connect to the spectrometer
                connect(app.spectrometerObj);
                
                % If the connection is successful, update the app UI
                app.StatusTextArea.Value = 'Connected to spectrometer successfully.';
                app.ConnectedLamp.Color = 'green';
                app.spectrometerIndex = 0; % Spectrometer index to use (first spectrometer by default).
                % Channel index to use (first channel by default).
                app.channelIndex = 0; % 0 or or 2? is this 0 1 or 2
                %                 intTime= invoke(app.spectrometerObj, 'getIntegrationTime', app.spectrometerIndex, app.channelIndex);
                %                 app.IntegrationTimesEditField.Value = string(intTime);
                app.integrationTime = str2double(app.IntegrationTimesEditField.Value);
                invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime);
                
            catch ME
                % If an error occurs during connection or operation
                errorMessage = sprintf('Error: Could not connect to spectrometer.\n%s', ME.message);
                app.StatusTextArea.Value = errorMessage;
                app.ConnectedLamp.Color = 'red';
                errordlg(errorMessage, 'Connection Error');
                
                % Delete the OmniDriver object
                delete(app.spectrometerObj);
                app.spectrometerObj = [];
            end
        end
        
        function startPreview(app)
            % Set up the preview timer
            app.previewTimer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 0.5, ...  % Adjust the period as needed
                'TimerFcn', @(~,~)updatePreview(app));
            start(app.previewTimer);
        end
        
        function stopPreview(app)
            if ~isempty(app.previewTimer) && isvalid(app.previewTimer)
                stop(app.previewTimer);
                delete(app.previewTimer);
                app.previewTimer = [];
            end
            % Clear the plot on the UIAxes
            cla(app.UIAxes);
        end
        
        function updatePreview(app)
            %set the integration time
            app.integrationTime = str2double(app.IntegrationTimesEditField.Value);
            
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime);
            app.wavelengths = invoke(app.spectrometerObj, 'getWavelengths', app.spectrometerIndex, app.channelIndex);
            
            app.intensities=invoke(app.spectrometerObj, 'getSpectrum', app.spectrometerIndex);%wrapper.getSpectrum(spectrometerIndex, channelIndex);
            %             pause(app.integrationTime/1000)
            % Get the spectrum from the spectrometer
            %             [wavelengths, intensities] = getSpectrum(app.spectrometerObj);
            % Update the plot or other UI elements with the spectrum data
            plot(app.UIAxes, app.wavelengths, app.intensities);
            xlabel(app.UIAxes, 'Wavelength (nm)');
            ylabel(app.UIAxes, 'Intensity (counts)');
            %             title(app.UIAxes, strcat('Spectral Preview - Max: ',string(round(app.intensities,2))))
            app.MaximumIntensityEditField.Value = round(max(app.intensities),2);
            if app.MaximumIntensityEditField.Value > 65000
                h = msgbox('Spectrometer is too close to saturation');
                uiwait(h);  % Wait for the user to acknowledge the message box
                
            end
        end
        
        function filename = constructUniqueFilename(app,baseFilename,todayFolder)
            filename = [baseFilename, '.mat'];
            copyNumber = 1;
            %             cd(todayFolder)
            while exist(filename, 'file')
                filename = sprintf('%s_copy%d.mat', baseFilename, copyNumber);
                copyNumber = copyNumber + 1;
                
            end
            %             cd ..
        end
        
        function captureFunc(app)
            % Temporarily turn off the preview if it's on
            %             previewWasOn = app.PreviewSwitch.Value;
            if strcmp(app.PreviewSwitch.Value, 'On')
                stopPreview(app);
            end
            
            % Check if the folder for today's date exists, if not, create it
            %             todayFolder = ['IAD_', datestr(now, 'YYYYMMDD')];
            t = datetime('today');
            d = yyyymmdd(t);
            todayFolder = strcat('IAD_',string(d));
            
            if ~exist(todayFolder, 'dir')
                mkdir(todayFolder);
                addpath(todayFolder)
            end
            addpath(todayFolder)
            
            % Set the integration time
            app.integrationTime = str2double(app.IntegrationTimesEditField.Value);
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime);
            
            app.wavelengths = invoke(app.spectrometerObj, 'getWavelengths', app.spectrometerIndex, app.channelIndex);
            app.intensities=invoke(app.spectrometerObj, 'getSpectrum', app.spectrometerIndex);%wrapper.getSpectrum(spectrometerIndex, channelIndex);
            numberOfPixels = length(app.intensities);
            
            for trial = 1:str2double(app.NumberofTrialsEditField.Value)
                
                % Initialize variables for averaging
                accumulatedSpectrum = zeros(numberOfPixels,1); % Assuming numberOfPixels is the number of pixels in your spectrometer
                for scan = 1:str2double(app.ScanstoAverageEditField.Value)
                    app.StatusTextArea.Value = strcat('Scan #',string(scan),'/',app.ScanstoAverageEditField.Value,' from Trial', string(trial),'out of', app.NumberofTrialsEditField.Value);
                    
                    % Acquire spectrum
                    invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime);
                    
                    app.intensities=invoke(app.spectrometerObj, 'getSpectrum', app.spectrometerIndex);%wrapper.getSpectrum(spectrometerIndex, channelIndex);
                    %             pause(app.integrationTime/1000)
                    % Get the spectrum from the spectromet
                    accumulatedSpectrum = accumulatedSpectrum + app.intensities;
                    
                                         accumulatedSpectrum2(:,:,scan) = app.intensities;
                    
                end
                % Average the spectrum
                app.StatusTextArea.Value = strcat('Averaging ', app.ScanstoAverageEditField.Value, 'scans');
                pause(1)
                averagedSpectrum = accumulatedSpectrum / str2double(app.ScanstoAverageEditField.Value);
                %                 averagedSpectrum2 = mean(squeeze(accumulatedSpectrum2),2);
                %                 figure;
                %                 plot(app.wavelengths,averagedSpectrum2,'r'); hold on
                %                 plot(app.wavelengths,averagedSpectrum,'k');
                
                
                % Construct the filename
                baseFilename = sprintf('%s_Trial%d_%s', app.SampleNameEditField.Value, trial, app.MeasurementDropDown.Value);
                filename = fullfile(todayFolder, constructUniqueFilename(app,baseFilename,todayFolder));
                
                % Save the data
                intTime= invoke(app.spectrometerObj, 'getIntegrationTime', app.spectrometerIndex, app.channelIndex);
                
                
                wavelengths =app.wavelengths;
                sampleName= app.SampleNameEditField.Value;
                averagedScans = app.ScanstoAverageEditField.Value;
                measurement = app.MeasurementDropDown.Value;
                NumberofTrials = app.NumberofTrialsEditField.Value;
                timestamp = datestr(now, 'yyyy-mm-dd HH:MM:SS');
                
                
                save(filename, 'averagedSpectrum','accumulatedSpectrum2','wavelengths', 'timestamp', ...
                    'sampleName', 'intTime', ...
                    'averagedScans','measurement' , ...
                    'NumberofTrials'); %should also store the model and serial of spectrometer
                
                
            end
            app.StatusTextArea.Value = strcat(app.MeasurementDropDown.Value,' data saved successfully: ',...
                app.SampleNameEditField.Value, 'Trial ',string(trial));
            
            % Turn the preview back on if it was on
            if strcmp(app.PreviewSwitch.Value, 'On')
                startPreview(app);
            else
                plot(app.UIAxes, app.wavelengths, averagedSpectrum);
                xlabel(app.UIAxes, 'Wavelength (nm)');
                ylabel(app.UIAxes, 'Intensity (counts)');
                title(app.UIAxes, 'Averaged Captured Spectrum');
            end
            
            app.ConnectedLamp.Color = 'green';
            
        end
        
        function calculateMRandMT(app)
            % Use the function to get the user-selected files
            selectedFiles = getUserSelectedFiles(app);
            
            % Check if the file selection was cancelled or incomplete
            if isempty(selectedFiles)
                return;  % Exit the function if file selection was incomplete
            end
            
            % Initialize variables
            app.wavelengths = [];
            
            % Load the spectral data from the selected files
            for i = 1:length(selectedFiles)
                
                data = load(string(selectedFiles{i}), "averagedSpectrum", "wavelengths","measurement");
                %                  pause(2)
                if isfield(data, 'averagedSpectrum')
                    switch data.measurement
                        case 'R_STD' % R_STD
                            R_STD = data.averagedSpectrum;
                        case 'R_EMPTY'
                            R_EMPTY = data.averagedSpectrum;
                        case 'R_SAMPLE'
                            R_SAMPLE = data.averagedSpectrum;
                            % Extract wavelengths from the R_SAMPLE file if it hasn't been loaded yet
                            if isempty(app.wavelengths) && isfield(data, 'wavelengths')
                                app.wavelengths = data.wavelengths;
                            end
                        case 'T_ZERO'
                            T_ZERO = data.averagedSpectrum;
                        case 'T_DARK'
                            T_DARK = data.averagedSpectrum;
                        case 'T_SAMPLE'
                            T_SAMPLE = data.averagedSpectrum;
                            % Extract wavelengths from the T_SAMPLE file if it hasn't been loaded yet
                            if isempty(app.wavelengths) && isfield(data, 'wavelengths')
                                app.wavelengths = data.wavelengths;
                            end
                    end
                else
                    error(['The file ' selectedFiles{i} ' does not contain the variable ''averagedSpectrum''.']);
                end
            end
            
            % Ensure that wavelengths were loaded
            if isempty(app.wavelengths)
                error('Wavelengths could not be loaded from the sample files.');
            end
            
            % Reflectance standard used is 99% reflective
            rstd = 0.99;
            
            % Calculate MR and MT using the loaded spectral data
            app.MR = rstd .* ((R_SAMPLE - R_EMPTY) ./ (R_STD - R_EMPTY));
            app.MT = (T_SAMPLE - T_DARK) ./ (T_ZERO - T_DARK);
            
            % Plot MR and MT on the UIAxes_2
            plotSpectra(app, app.wavelengths, app.MR, app.MT);
        end
        
        function fileList = getUserSelectedFiles(app)
            % Define the required measurement types
            requiredTypes = {'R_STD', 'R_EMPTY', 'R_SAMPLE', 'T_ZERO', 'T_DARK', 'T_SAMPLE'};
            
            % Initialize the file list and selected types
            fileList = {};
            selectedTypes = {};
            
            % Notify the user to select files via a pop-up message box
            h = msgbox('Please select the measurement files.', 'Select Files', 'help');
            uiwait(h);  % Wait for the user to acknowledge the message box
            
            % Display a file selection dialog for the user to select multiple files
            [files, path] = uigetfile('*.mat', 'Select the measurement files:', 'MultiSelect', 'on');
            
            % If the user cancels the dialog, exit the function
            if isequal(files, 0)
                app.StatusTextArea.Value = 'File selection cancelled.';
                fileList = {};  % Clear the list to indicate the process was not completed
                return;
            end
            
            % Convert to cell array if only one file is selected
            if ischar(files)
                files = {files};
            end
            
            % Construct the full file paths and update selected types
            fileList = cellfun(@(file) fullfile(path, file), files, 'UniformOutput', false);
            for i = 1:length(fileList)
                selectedTypes{i} = app.extractMeasurementType(fileList{i}, requiredTypes);
            end
            
            % Check if the user selected more than 6 files
            if length(files) > length(requiredTypes)
                h = msgbox('Too many files selected. Please select exactly 6 files.', 'Error', 'error');
                uiwait(h);  % Wait for the user to acknowledge the message box
                fileList = {};  % Clear the list to indicate the process was not completed
                return;
            end
            
            % Check for repeated measurement types
            if length(unique(selectedTypes)) < length(selectedTypes)
                h = msgbox('Repeated measurement types detected. Please select unique files for each type.', 'Error', 'error');
                uiwait(h);  % Wait for the user to acknowledge the message box
                fileList = {};  % Clear the list to indicate the process was not completed
                return;
            end
            
            % Check if all required types are present in the selected files
            if ~all(cellfun(@(type) any(strcmp(selectedTypes, type)), requiredTypes))
                missingTypes = requiredTypes(~ismember(requiredTypes, selectedTypes));
                missingTypesStr = strjoin(missingTypes, ', ');
                app.StatusTextArea.Value = sprintf('Missing files for: %s. Please select all files again.', missingTypesStr);
                fileList = {};  % Reset the file list for re-selection
                selectedTypes = {};  % Reset the selected types
                return;
            else
                app.StatusTextArea.Value = 'Files selected successfully.';
            end
        end
        
        
        function plotSpectra(app, wavelengths, MR, MT)
            % Plot the calculated MR and MT against the wavelengths
            plot(app.UIAxes_2, wavelengths, MR, 'b', 'DisplayName', 'MR');
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, wavelengths, MT, 'r', 'DisplayName', 'MT');
            hold(app.UIAxes_2, 'off');
            xlabel(app.UIAxes_2, 'Wavelength (nm)');
            ylabel(app.UIAxes_2, 'Reflectance/Transmittance');
            title(app.UIAxes_2, 'Measured Reflectance and Transmittance');
            legend(app.UIAxes_2, 'show');
            drawnow; % Refresh the plot
        end
        
        function selectedFilesStatus = updateSelectedFilesStatus(app, statusText, measurementTypes, fileList, currentStep)
            % Initialize status text if it's empty
            if isempty(statusText)
                statusText = 'Selected files:';
            end
            
            % Update the status text with the files selected so far
            for j = 1:(currentStep - 1)
                statusText = sprintf('%s\n%s: %s', statusText, measurementTypes{j}, fileList{j});
            end
            
            % Add the current file selection prompt to the status
            statusText = sprintf('%s\nPlease select: %s', statusText, measurementTypes{currentStep});
            
            % Return the updated status text
            selectedFilesStatus = statusText;
        end
        
        function type = extractMeasurementType(app, filename, requiredTypes)
            % Extract the measurement type from the filename based on the required types
            for i = 1:length(requiredTypes)
                if contains(filename, requiredTypes{i})
                    type = requiredTypes{i};
                    return;
                end
            end
            type = '';  % Return empty string if no type is found
        end
        
        
        function createInputForIAD(app)
            % Prompt the user for a filename suffix
            prompt = {'Enter a suffix for the filename:'};
            dlgtitle = 'Input Filename Suffix';
            dims = [1 35];
            definput = {''};
            suffix = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Check if the user canceled the input dialog
            if isempty(suffix)
                return;  % Exit the function if no input was provided
            end
            
            % Construct the filename
            filename = sprintf('IAD_input_%s.txt', suffix{1});
            
            % Check if the file already exists
            if isfile(filename)
                choice = questdlg('The file already exists. Do you want to overwrite it?', ...
                    'File Exists', ...
                    'Yes', 'No', 'Yes');
                if strcmp(choice, 'No')
                    return;  % Exit the function if the user does not want to overwrite the file
                end
            end
            
            % Open the file for writing
            fileID = fopen(filename, 'w');
            
            % Write the header to the file
            fprintf(fileID, "IAD1\n#\n");
            fprintf(fileID, "# Sample Trial (1 inch port)\n");
            fprintf(fileID, "# 4 inch sphere\n\n");
            fprintf(fileID, "%s # Index of refraction of the sample\n", app.MaterialRefractiveIndexEditField.Value);
            fprintf(fileID, "1.00 # Index of refraction of the top and bottom slides\n");
            fprintf(fileID, "%s # [mm] Thickness of sample\n", app.SampleThicknessmmEditField.Value);
            fprintf(fileID, "0.0 # [mm] Thickness of slides\n");
            fprintf(fileID, "%s # [mm] Diameter of illumination beam\n", app.BeamWidthmmEditField.Value);
            fprintf(fileID, "0.99 # Reflectivity of the reflectance calibration standard\n\n");
            fprintf(fileID, "1 # Number of spheres used during each measurement\n\n");
            fprintf(fileID, "       # Properties of sphere used for reflection measurements\n");
            fprintf(fileID, "101.6  # [mm] Sphere Diameter  (4 in * 25.4 mm/in)\n");
            fprintf(fileID, "25.0   # [mm] Sample Port Diameter\n");
            fprintf(fileID, "25.0   # [mm] Entrance Port Diameter\n");
            fprintf(fileID, "1.00   # [mm] Detector Port Diameter\n");
            fprintf(fileID, "0.96   # Reflectivity of the sphere wall\n\n");
            fprintf(fileID, "       # Properties of sphere used for transmission measurements\n\n");
            fprintf(fileID, "101.6  # [mm] Sphere Diameter  (4 in * 25.4 mm/in)\n");
            fprintf(fileID, "25.0   # [mm] Sample Port Diameter\n");
            fprintf(fileID, "25.0   # [mm] Entrance Port Diameter\n");
            fprintf(fileID, "1.00   # [mm] Detector Port Diameter\n");
            fprintf(fileID, "0.96   # Reflectivity of the sphere wall\n\n");
            fprintf(fileID, "2      # Two measurements, i.e., M_R & M_T\n\n");
            
            % Write the data
            fprintf(fileID, '%12s %12s %12s\n', '#', 'M_R', 'M_T');
            for i = 1:length(app.wavelengths)
                if app.wavelengths(i) >= str2double(app.WavelengthRangenmEditField.Value) && ...
                        app.wavelengths(i) <= str2double(app.toEditField.Value)
                    fprintf(fileID, '%12.8f %12.8f %12.8f\n', app.wavelengths(i), app.MR(i), app.MT(i));
                end
            end
            
            % Close the file
            fclose(fileID);
            
            % Store the file path for later use
            app.InputFilePath = filename;
            
            % Notify the user that the file has been created
            msgbox(['Input file created: ' filename], 'File Created', 'info');
            
            %             %create the rxt
            %             checkAndConvertRXT(app)
        end


        function loadData(app)
            % Ask the user to select a file to load
            [file, path] = uigetfile({'*.txt;*.mat'}, 'Select IAD Input or Results File');
            if isequal(file, 0) || isequal(path, 0)
                return;  % Exit the function if no file was selected
            end
            filename = fullfile(path, file);
            
            % If the selected input file is not in the current working directory, create a copy in the current working directory
            if ~strcmp(path, pwd)
                [~, filenameWithoutExtension, extension] = fileparts(filename);
                newFilename = [filenameWithoutExtension, extension];
                copyfile(filename, fullfile(pwd, newFilename));
                filename = fullfile(pwd, newFilename);
            end
            
            
            % Update app.InputFilePath with the selected file path
            app.InputFilePath = filename;
            
            % Determine if the file is an input or results file
            if contains(filename, 'IAD_input')
                % Load IAD input file
                app.StatusTextArea.Value = ['Loaded IAD input file: ' filename];
                
                % Create a backup copy of the input file in the iadInputsUsed folder
                backupFolder = fullfile(pwd, 'iadInputsUsed');
                if ~exist(backupFolder, 'dir')
                    mkdir(backupFolder);
                end
                copyfile(filename, fullfile(backupFolder, file));
                
                % Read the header information
                fileID = fopen(filename, 'r');
                for i = 1:30  % Assuming the header is about 30 lines
                    line = fgetl(fileID);
                    if contains(line, '# Index of refraction of the sample')
                        n_samp = strtrim(extractBefore(line, '#'));
                        app.MaterialRefractiveIndexEditField.Value = n_samp;
                    elseif contains(line, '# [mm] Thickness of sample')
                        thck_samp = strtrim(extractBefore(line, '#'));
                        app.SampleThicknessmmEditField.Value = thck_samp;
                    elseif contains(line, '# [mm] Diameter of illumination beam')
                        beam_diam = strtrim(extractBefore(line, '#'));
                        app.BeamWidthmmEditField.Value = beam_diam;
                    end
                end
                fclose(fileID);
                
                % Read the wavelengths, MR, and MT data starting from line 32
                data = dlmread(filename, '', 32, 0);
                wavelengths = data(:, 1);
                MR = data(:, 2);
                MT = data(:, 3);
                
                % Update the app properties
                app.wavelengths = wavelengths;
                app.MR = MR;
                app.MT = MT;
                
                % Update UIAxes_2 with the loaded data
                plot(app.UIAxes_2, wavelengths, MR, wavelengths, MT);
                legend(app.UIAxes_2, 'MR', 'MT');
                xlabel(app.UIAxes_2, 'Wavelength (nm)');
                ylabel(app.UIAxes_2, 'Reflectance/Transmittance');
                
                % Update the wavelength range EditFields based on the data
                app.WavelengthRangenmEditField.Value = num2str(wavelengths(1));
                app.toEditField.Value = num2str(wavelengths(end));
                
            elseif contains(filename, 'IAD_results')
                % Load IAD results file
                app.StatusTextArea.Value = ['Loaded IAD results file: ' filename];
                results = load(filename);
                
                % Update the app EditFields with results information
                app.NumberofPhotonsEditField.Value = num2str(results.nPhotons);
                app.AnisotropyfactorgEditField.Value = num2str(results.g);
                
                % Update UIAxes_3 and UIAxes_4 with the loaded data
                plot(app.UIAxes_3, results.wavelength, results.mua);
                xlabel(app.UIAxes_3, 'Wavelength (nm)');
                ylabel(app.UIAxes_3, '\mu_a [cm^{-1}]');
                title(app.UIAxes_3, 'Absorption Spectrum');
                
                plot(app.UIAxes_4, results.wavelength, results.musp);
                xlabel(app.UIAxes_4, 'Wavelength (nm)');
                ylabel(app.UIAxes_4, '\mu_s'' [cm^{-1}]');
                title(app.UIAxes_4, 'Reduced Scattering Spectrum');
                
            else
                % File type not recognized
                app.StatusTextArea.Value = 'File type not recognized. Please select a valid IAD input or results file.';
            end
        end
        
        function checkAndConvertRXT(app)
            % Check if the current InputFilePath is a .txt file
            if endsWith(app.InputFilePath, '.txt')
                % Construct the corresponding .rxt file name
                [path, name, ~] = fileparts(app.InputFilePath);
                rxtFileName = fullfile(path, [name '.rxt']);
                
                % Check if the .rxt file already exists
                if exist(rxtFileName, 'file')
                    app.StatusTextArea.Value = ['.rxt file already exists: ' rxtFileName];
                else
                    % Convert the .txt file to .rxt format
                    txtData = fileread(app.InputFilePath);
                    fileID = fopen(rxtFileName, 'w');
                    fwrite(fileID, txtData);
                    fclose(fileID);
                    
                    % Update the status text area
                    app.StatusTextArea.Value = ['.txt file converted to .rxt: ' rxtFileName];
                end
                
                % Update the InputFilePath to the new .rxt file
                app.InputFilePath = rxtFileName;
            else
                app.StatusTextArea.Value = 'Current input file is not a .txt file.';
            end
        end
        
        function runIAD(app)
            %             %create rxt file
            checkAndConvertRXT(app)
            % Define the base name for the run file
            SaveBaseName = '.rxt';
            
            % Construct the save file name
            [~, name, ~] = fileparts(app.InputFilePath);
            SaveFileName = [name SaveBaseName];
            
            % Update the status text area
            app.StatusTextArea.Value = 'Running IAD on dataset';
            
            % Get the anisotropy factor and number of photons from the app interface
            g = app.AnisotropyfactorgEditField.Value;
            nPhotons = app.NumberofPhotonsEditField.Value;
            
            % Check the operating system and run the IAD command accordingly
            if ispc  % Windows
                command = sprintf('iad -g %s -p %s %s', g, nPhotons, SaveFileName);
            elseif ismac  % macOS
                command = sprintf('./iad -g %s -p %s %s', g, nPhotons, SaveFileName);
                % Placeholder for macOS command, adjust as needed
            else
                error('Unsupported operating system');
            end
            
            % Run the IAD command
            [status, cmdout] = system(command);
            
            
        end
    end
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            connectToSpectrometer(app)
            
        end

        % Value changed function: PreviewSwitch
        function PreviewSwitchValueChanged(app, event)
            value = app.PreviewSwitch.Value;
            if strcmp(value,'Off')
                %                 app.ConnectedLamp.Color = [1,0,0];
                stopPreview(app)
            else
                startPreview(app)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {781, 781};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {267, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            %             app.StatusTextArea.Value = strcat('Capturing sample \n', app.SampleNameEditField.Value,' ', app.MeasurementDropDown.Value);
            app.ConnectedLamp.Color = [1,0,0];
            pause(1.5)
            captureFunc(app)
            
        end

        % Value changed function: IntegrationTimesEditField
        function IntegrationTimesEditFieldValueChanged(app, event)
            app.integrationTime = str2double(app.IntegrationTimesEditField.Value);
            %             invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime);
            
            %
        end

        % Button pushed function: GetMRMTandcreateIADinputButton
        function GetMRMTandcreateIADinputButtonPushed(app, event)
            %
            %ask user to select necessary files to calcualte MR and MT to
            %plot and store
            
            calculateMRandMT(app)
            %create .txt file for IAD input
            createInputForIAD(app)


        end

        % Button pushed function: SolveIADButton
        function SolveIADButtonPushed(app, event)
            runIAD(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loadData(app)
        end

        % Value changed function: MaximumIntensityEditField
        function MaximumIntensityEditFieldValueChanged(app, event)
            value = app.MaximumIntensityEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SampleNameEditFieldLabel = uilabel(app.LeftPanel);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.LeftPanel, 'text');
            app.SampleNameEditField.Value = 'E50AV1_FST3-4_2p5mm_A';
            app.IntegrationTimesEditFieldLabel = uilabel(app.LeftPanel);
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time (μs)';
            app.IntegrationTimesEditField = uieditfield(app.LeftPanel, 'text');
            app.IntegrationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimesEditFieldValueChanged, true);
            app.IntegrationTimesEditField.Value = '6850000';
            app.ScanstoAverageEditFieldLabel = uilabel(app.LeftPanel);
            app.ScanstoAverageEditFieldLabel.Text = 'Scans to Average';
            app.ScanstoAverageEditField = uieditfield(app.LeftPanel, 'text');
            app.ScanstoAverageEditField.Value = '5';
            app.NumberofTrialsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.LeftPanel, 'text');
            app.NumberofTrialsEditField.Value = '1';
            app.BeamWidthmmEditFieldLabel = uilabel(app.LeftPanel);
            app.BeamWidthmmEditFieldLabel.Text = 'Beam Width (mm) ';
            app.BeamWidthmmEditField = uieditfield(app.LeftPanel, 'text');
            app.BeamWidthmmEditField.Value = '3.00';
            app.SampleThicknessmmEditFieldLabel = uilabel(app.LeftPanel);
            app.SampleThicknessmmEditFieldLabel.Text = 'Sample Thickness (mm) ';
            app.SampleThicknessmmEditField = uieditfield(app.LeftPanel, 'text');
            app.SampleThicknessmmEditField.Value = '2.00';
            app.MaterialRefractiveIndexEditFieldLabel = uilabel(app.LeftPanel);
            app.MaterialRefractiveIndexEditFieldLabel.Text = 'Material Refractive Index';
            app.MaterialRefractiveIndexEditField = uieditfield(app.LeftPanel, 'text');
            app.MaterialRefractiveIndexEditField.Value = '1.47';
            app.WavelengthRangenmEditFieldLabel = uilabel(app.LeftPanel);
            app.WavelengthRangenmEditFieldLabel.Text = 'Wavelength Range (nm)';
            app.WavelengthRangenmEditField = uieditfield(app.LeftPanel, 'text');
            app.WavelengthRangenmEditField.Value = '500';
            app.toEditFieldLabel = uilabel(app.LeftPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.LeftPanel, 'text');
            app.toEditField.Value = '850';
            app.CaptureButton = uibutton(app.LeftPanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.SolveIADButton = uibutton(app.LeftPanel, 'push');
            app.SolveIADButton.ButtonPushedFcn = createCallbackFcn(app, @SolveIADButtonPushed, true);
            app.SolveIADButton.Text = 'Solve IAD';
            app.MeasurementDropDownLabel = uilabel(app.LeftPanel);
            app.MeasurementDropDownLabel.Text = 'Measurement';
            app.MeasurementDropDown = uidropdown(app.LeftPanel);
            app.MeasurementDropDown.Items = {'R_STD', 'R_EMPTY', 'R_SAMPLE', 'T_ZERO', 'T_DARK', 'T_SAMPLE'};
            app.MeasurementDropDown.Value = 'R_STD';
            app.ConnectedLamp = uilamp(app.LeftPanel);
            app.PreviewSwitchLabel = uilabel(app.LeftPanel);
            app.PreviewSwitchLabel.Text = 'Preview';
            app.PreviewSwitch = uiswitch(app.LeftPanel, 'slider');
            app.PreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @PreviewSwitchValueChanged, true);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load ';
            app.NumberofPhotonsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofPhotonsEditFieldLabel.Text = 'Number of Photons';
            app.NumberofPhotonsEditField = uieditfield(app.LeftPanel, 'text');
            app.NumberofPhotonsEditField.Value = '1000000';
            app.IADInputLabel = uilabel(app.LeftPanel);
            app.IADInputLabel.Text = 'IAD Input';
            app.SpectralAcquisitionLabel = uilabel(app.LeftPanel);
            app.SpectralAcquisitionLabel.Text = 'Spectral Acquisition';
            app.StatusTextAreaLabel = uilabel(app.LeftPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.LeftPanel);
            app.GetMRMTandcreateIADinputButton = uibutton(app.LeftPanel, 'push');
            app.GetMRMTandcreateIADinputButton.ButtonPushedFcn = createCallbackFcn(app, @GetMRMTandcreateIADinputButtonPushed, true);
            app.GetMRMTandcreateIADinputButton.Text = 'Get MR, MT, and create IAD input';
            app.AnisotropyfactorgEditFieldLabel = uilabel(app.LeftPanel);
            app.AnisotropyfactorgEditFieldLabel.Text = 'Anisotropy factor (g)';
            app.AnisotropyfactorgEditField = uieditfield(app.LeftPanel, 'text');
            app.AnisotropyfactorgEditField.Value = '0.9';
            app.SpecificWavelengthEditFieldLabel = uilabel(app.LeftPanel);
            app.SpecificWavelengthEditFieldLabel.Text = 'Specific Wavelength';
            app.SpecificWavelengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpecificWavelengthEditField.Value = 660;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Spectral Preview')
            xlabel(app.UIAxes, 'Wavelength [nm]')
            ylabel(app.UIAxes, 'Count (a.u.)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [400 1000];
            app.UIAxes.YLim = [0 65000];
            app.UIAxes.XTick = [400 500 600 700 800 900 1000];
            app.UIAxes.XTickLabel = {'400'; '500'; '600'; '700'; '800'; '900'; '1000'};
            app.UIAxes.YTick = [0 10000 20000 30000 40000 50000 60000];
            app.UIAxes.YTickLabel = {'0'; '10000'; '20000'; '30000'; '40000'; '50000'; '60000'};
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Measured Reflectance and Transmittance')
            xlabel(app.UIAxes_2, 'Wavelength [nm]')
            ylabel(app.UIAxes_2, '(%)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [400 1000];
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Absorption')
            xlabel(app.UIAxes_3, 'Wavelength [nm]')
            ylabel(app.UIAxes_3, 'μ_a')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [400 1000];
            app.UIAxes_3.YLim = [0 1];
            app.UIAxes_3.YTick = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5];
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Scattering')
            xlabel(app.UIAxes_4, 'Wavelength [nm]')
            ylabel(app.UIAxes_4, {'μ_s'''; ''})
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XLim = [400 1000];
            app.UIAxes_4.YLim = [0 1];
            app.AbsorptionatSpecifiedWavelengthLabel = uilabel(app.RightPanel);
            app.AbsorptionatSpecifiedWavelengthLabel.Text = 'Absorption at Specified Wavelength ';
            app.AbsorptionatSpecifiedWavelengthEditField = uieditfield(app.RightPanel, 'numeric');
            app.ScatteringatSpecifiedWavelengthEditFieldLabel = uilabel(app.RightPanel);
            app.ScatteringatSpecifiedWavelengthEditFieldLabel.Text = 'Scatteringat Specified Wavelength ';
            app.ScatteringatSpecifiedWavelengthEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaximumIntensityEditFieldLabel = uilabel(app.RightPanel);
            app.MaximumIntensityEditFieldLabel.Text = 'Maximum Intensity';
            app.MaximumIntensityEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaximumIntensityEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumIntensityEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IAD_CAPTURE_SOLVE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end